classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LatticeGUIUIFigure       matlab.ui.Figure
        EigenvaluePlot           matlab.ui.control.UIAxes
        LatticePlot              matlab.ui.control.UIAxes
        MSliderLabel             matlab.ui.control.Label
        MSlider                  matlab.ui.control.Slider
        alphaSliderLabel         matlab.ui.control.Label
        alphaSlider              matlab.ui.control.Slider
        pumpSliderLabel          matlab.ui.control.Label
        pumpSlider               matlab.ui.control.Slider
        lossSliderLabel          matlab.ui.control.Label
        lossSlider               matlab.ui.control.Slider
        CalculateButton          matlab.ui.control.Button
        TimelimitEditFieldLabel  matlab.ui.control.Label
        TimelimitEditField       matlab.ui.control.NumericEditField
        FFTPlot                  matlab.ui.control.UIAxes
        zoomButton               matlab.ui.control.Button
        rescaleButton            matlab.ui.control.Button
        panButton                matlab.ui.control.Button
        saveButton               matlab.ui.control.Button
    end


    properties (Access = public)
        data % Description
    end

    methods (Access = private)
    
        function  replotEig(app)
            [app.data.egs,app.data.lattice]=calculate(app.pumpSlider.Value,app.MSlider.Value,...
                app.lossSlider.Value,app.alphaSlider.Value);
            scatter(app.EigenvaluePlot,real(app.data.egs.values), imag(app.data.egs.values), 'r');
            app.EigenvaluePlot.Children.ButtonDownFcn=...
                createCallbackFcn(app, @eigenvaluePlotCallback, true);
        end
        function replotMode(app)
            Visual.plotEig(app.data.egs,app.data.seig,app.LatticePlot);
        end
        function eigenvaluePlotCallback(app,event)
            %find hitpoint
            points=event.IntersectionPoint;
            hitpnt=abs(real(app.data.egs.values)-points(1))...
                +abs(imag(app.data.egs.values)-points(2));
            [~,index]=min(hitpnt);
            app.data.seig=index;
            disp(index)
            replotMode(app);
        end
        
    end


    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            include;
            replotEig(app);
            Visual.showNodes(app.data.lattice,app.LatticePlot);
            app.EigenvaluePlot.Children.ButtonDownFcn=...
                createCallbackFcn(app, @eigenvaluePlotCallback, true);
        end

        % Callback function: MSlider, alphaSlider, lossSlider, 
        % pumpSlider
        function MSliderValueChanging(app, event)
           % changingValue = event.Value;
            replotEig(app);
        end

        % Button pushed function: CalculateButton
        function CalculateButtonPushed(app, event)
            app.data.soln=Solver.calctime(app.data.lattice,app.TimelimitEditField.Value);
            Visual.plotfft(app.data.soln,0,app.FFTPlot);
            Visual.graphTimeAmp(app.data.soln,1,100,100,app.LatticePlot);
        end

        % Button pushed function: zoomButton
        function zoomButtonPushed(app, event)
            zoom(app.FFTPlot,'on');
        end

        % Button pushed function: rescaleButton
        function rescaleButtonPushed(app, event)
            app.FFTPlot.XLimMode = 'auto';
            app.FFTPlot.YLimMode = 'auto';
        end

        % Button pushed function: panButton
        function panButtonPushed(app, event)
            pan(app.FFTPlot,'on');
        end

        % Button pushed function: saveButton
        function saveButtonPushed(app, event)
            imputfile
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LatticeGUIUIFigure = uifigure;
            app.LatticeGUIUIFigure.Name = 'Lattice GUI';
            app.EigenvaluePlot = uiaxes(app.LatticeGUIUIFigure);
            title(app.EigenvaluePlot, 'Eigenvalues')
            xlabel(app.EigenvaluePlot, 'Real')
            ylabel(app.EigenvaluePlot, 'Imag')
            app.LatticePlot = uiaxes(app.LatticeGUIUIFigure);
            title(app.LatticePlot, 'Lattice')
            xlabel(app.LatticePlot, 'X')
            ylabel(app.LatticePlot, 'Y')
            app.MSliderLabel = uilabel(app.LatticeGUIUIFigure);
            app.MSliderLabel.Text = 'M';
            app.MSlider = uislider(app.LatticeGUIUIFigure);
            app.MSlider.Limits = [0 5];
            app.MSlider.ValueChangingFcn = createCallbackFcn(app, @MSliderValueChanging, true);
            app.alphaSliderLabel = uilabel(app.LatticeGUIUIFigure);
            app.alphaSliderLabel.Text = 'alpha';
            app.alphaSlider = uislider(app.LatticeGUIUIFigure);
            app.alphaSlider.Limits = [0 5];
            app.alphaSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanging, true);
            app.pumpSliderLabel = uilabel(app.LatticeGUIUIFigure);
            app.pumpSliderLabel.Text = 'pump';
            app.pumpSlider = uislider(app.LatticeGUIUIFigure);
            app.pumpSlider.Limits = [0 10];
            app.pumpSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanging, true);
            app.lossSliderLabel = uilabel(app.LatticeGUIUIFigure);
            app.lossSliderLabel.Text = 'loss';
            app.lossSlider = uislider(app.LatticeGUIUIFigure);
            app.lossSlider.Limits = [0 10];
            app.lossSlider.ValueChangedFcn = createCallbackFcn(app, @MSliderValueChanging, true);
            app.CalculateButton = uibutton(app.LatticeGUIUIFigure, 'push');
            app.CalculateButton.ButtonPushedFcn = createCallbackFcn(app, @CalculateButtonPushed, true);
            app.CalculateButton.Text = 'Calculate';
            app.TimelimitEditFieldLabel = uilabel(app.LatticeGUIUIFigure);
            app.TimelimitEditFieldLabel.Text = 'Timelimit';
            app.TimelimitEditField = uieditfield(app.LatticeGUIUIFigure, 'numeric');
            app.TimelimitEditField.Value = 100;
            app.FFTPlot = uiaxes(app.LatticeGUIUIFigure);
            title(app.FFTPlot, 'Fourier Transorm')
            xlabel(app.FFTPlot, 'Frequency')
            ylabel(app.FFTPlot, 'Log(Abs(E^2))')
            app.zoomButton = uibutton(app.LatticeGUIUIFigure, 'push');
            app.zoomButton.ButtonPushedFcn = createCallbackFcn(app, @zoomButtonPushed, true);
            app.zoomButton.Text = '🔎';
            app.rescaleButton = uibutton(app.LatticeGUIUIFigure, 'push');
            app.rescaleButton.ButtonPushedFcn = createCallbackFcn(app, @rescaleButtonPushed, true);
            app.rescaleButton.Text = '💯';
            app.panButton = uibutton(app.LatticeGUIUIFigure, 'push');
            app.panButton.ButtonPushedFcn = createCallbackFcn(app, @panButtonPushed, true);
            app.panButton.Text = '🍳';
            app.saveButton = uibutton(app.LatticeGUIUIFigure, 'push');
            app.saveButton.ButtonPushedFcn = createCallbackFcn(app, @saveButtonPushed, true);
            app.saveButton.Text = '💾';
        end
    end

    methods (Access = public)

        % Construct app
        function app = GUI

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LatticeGUIUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LatticeGUIUIFigure)
        end
    end
end