classdef LatticeEditor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        FileMenu                    matlab.ui.container.Menu
        EditMenu                    matlab.ui.container.Menu
        MovelatticeMenu             matlab.ui.container.Menu
        MarkunitcellMenu            matlab.ui.container.Menu
        TabGroup                    matlab.ui.container.TabGroup
        EditorTab                   matlab.ui.container.Tab
        EquationDropDownLabel       matlab.ui.control.Label
        EquationDropDown            matlab.ui.control.DropDown
        EquationParametersLabel     matlab.ui.control.Label
        UITable                     matlab.ui.control.Table
        LinkstrengthEditFieldLabel  matlab.ui.control.Label
        LinkstrengthEditField       matlab.ui.control.EditField
        LinktypeButtonGroup         matlab.ui.container.ButtonGroup
        HermitianButton             matlab.ui.control.RadioButton
        nonHermitianButton          matlab.ui.control.RadioButton
        SaveLatticeButton           matlab.ui.control.Button
        LoadLatticeButton           matlab.ui.control.Button
        UITablePar                  matlab.ui.control.Table
        LatticeParametersLabel      matlab.ui.control.Label
        ButtonAddLatPar             matlab.ui.control.Button
        ButtonRemLatPar             matlab.ui.control.Button
        ClearLatticeButton          matlab.ui.control.Button
        MakeFiniteLatticeButton     matlab.ui.control.Button
        PrimitiveVectorsLabel       matlab.ui.control.Label
        UITableVectors              matlab.ui.control.Table
        DeleteNodeButton            matlab.ui.control.Button
        DeleteLinkButton            matlab.ui.control.Button
        Label                       matlab.ui.control.Label
        Message                     matlab.ui.control.EditField
        UIAxes                      matlab.ui.control.UIAxes
        EigenvaluesolverTab         matlab.ui.container.Tab
        UIAxes_eig                  matlab.ui.control.UIAxes
        SliderLabel                 matlab.ui.control.Label
        Slider                      matlab.ui.control.Slider
    end

    
    properties (Access = private)
        lattice;
        currentEqn;
        selectedNode;
        selectedNodeScatter;
        %selectedLink;
        selectedLinkArrow=0;
        linkStrength=1;
        N_nodes;
        eqnlist;
        nodeRadius=50 % Description
        primitiveVectors;
        unitcell;
    end
    
    methods (Access = private)
        
        function results = PlotCallback(app,event)
            %find hitpoint
            app.UIAxes.NextPlot='add';
            points=event.IntersectionPoint;
            disp(points)
            %check if there is already a node at x,y
            if isempty(getNodesAtPosition(app,points(1),points(2)))
                addNode(app,points(1),points(2));
            end
            %replotMode(app);
        end
        function plotGrid(app)
            [x, y] = meshgrid(1:20, 1:20);
            pl=scatter3(app.UIAxes,x(:),y(:),-1*ones(size(x(:))),'filled');
            view(app.UIAxes,2);
            grid(app.UIAxes,'on');
            pl.MarkerFaceAlpha = 0.3;
            app.UIAxes.Children.ButtonDownFcn=createCallbackFcn(app, @PlotCallback, true);
            %set(app.UIAxes.Children,'HitTest','off');
            app.UIAxes.Children.PickableParts='all';
        end
        %% When you click a node, this function will run
        function NodeCallback(app,event)
            points=event.IntersectionPoint;
            if event.Button==1 %
                %disp(event.Source.UserData.node)
                app.selectedNode=event.Source.UserData.node;
                highlightSelectedNode(app,event.Source);
                %update current equation
                app.currentEqn=event.Source.UserData.node.eqn;
                %update equation table
                updateEqnTable(app);
                %update equation drop down menu
                if any(strcmp(app.EquationDropDown.Items,class(app.currentEqn))')%check eqn on the list
                    app.EquationDropDown.Value=class(app.currentEqn);
                else
                    app.Message.Value='Warning: Eqn not on the list';
                end
            elseif event.Button==3 %LINK TWO NODES
                %Self-linking is not allowed
                if app.selectedNode.ID~=event.Source.UserData.node.ID
                    %Double linking is now allowed
                    if ~Node.islinked(app.selectedNode,event.Source.UserData.node);
                        [link1,~]=Node.attach(app.selectedNode,event.Source.UserData.node,app.linkStrength);
                        arrow=createLinkArrow(app,app.selectedNode.x,app.selectedNode.y,points(1),points(2));
                        %highlight the new link
                        if (app.selectedLinkArrow==0)
                            app.selectedLinkArrow=arrow;
                        else
                            unhighlightLink(app,app.selectedLinkArrow);
                        end
                        highlightLink(app,arrow);
                        app.selectedLinkArrow=arrow;
                        %keep link and nodes in the arrow object
                        app.selectedLinkArrow.UserData.link=link1;
                        app.selectedLinkArrow.UserData.nodes=[app.selectedNode,event.Source.UserData.node];
                    else
                        app.Message.Value='Double-linking is not allowed';
                    end
                else
                    app.Message.Value='Self-linking is not allowed';
                end
            end
        end
        function arrow=createLinkArrow(app,x1,y1,x2,y2)
            arrow=quiver(app.UIAxes,x1,y1,x2-x1,y2-y1...
                ,'LineWidth',2,'MaxHeadSize',4,'color',[0.4 0 0.2]);
            arrow.ButtonDownFcn=createCallbackFcn(app, @arrowCallback, true);
        end
        function highlightLink(app,linkArrow)
            linkArrow.Color=[0.4 0.5 0.2];
        end
        function unhighlightLink(app,linkArrow)
            linkArrow.Color=[0.4 0 0.2];
        end
        function arrowCallback(app,event)
            if (app.selectedLinkArrow~=0)
                if (~isempty(app.selectedLinkArrow))
                    unhighlightLink(app,app.selectedLinkArrow);
                end
            end
            highlightLink(app,event.Source);
            app.selectedLinkArrow=event.Source;
            app.LinkstrengthEditField.Value=num2str(event.Source.UserData.link.str);
        end
        function addNode(app,x,y)
            pl=scatter3(app.UIAxes,round(x),round(y),1,app.nodeRadius,'filled','r');
            pl.ButtonDownFcn=createCallbackFcn(app, @NodeCallback, true);
            app.N_nodes=app.N_nodes+1;
            newNode=Node(app.N_nodes);
            pl.UserData.node=newNode;
            newNode.x=x;
            newNode.y=y;
            newNode.eqn=app.currentEqn;
            newNode.ID=app.N_nodes;
            app.lattice.nodes=[app.lattice.nodes(:); newNode];
            app.selectedNode=newNode;
            highlightSelectedNode(app,pl);
        end
        function highlightSelectedNode(app,newnode)%<-newnode is a Scatter object
            %clear the edge color of the old node
            if ~isempty(app.selectedNodeScatter)
                app.selectedNodeScatter.MarkerEdgeColor='none';
            end
            %add edge color to the new node
            newnode.MarkerEdgeColor=[0 0 0];
            %update current node
            app.selectedNodeScatter=newnode;
        end
        function updateEqnTable(app)
            %app.UITable.ColumnFormat={'char','char'};
            names=fieldnames(app.currentEqn.par);
            values=struct2cell(app.currentEqn.par);
            %            for i=1:length(values)
            %                values{i}=num2str(values{i});
            %            end
            %values={num2str([values{:}]')};
            app.UITable.Data=[names,cellfun(@num2str, values, 'un', 0)];
            %app.UITable.ColumnFormat={'char','char'};
        end
        function undrawLattice(app)
            cla(app.UIAxes,'reset'); % Do a complete and total reset of the axes.
            app.selectedNode=Node.empty();
            app.selectedNodeScatter=[];
            app.selectedLinkArrow=0;
            app.primitiveVectors=[];
            plotGrid(app);
            app.N_nodes=0;
            UpdatePrimitiveVectors(app);
        end
        function redrawLattice(app)
            redrawNodes(app);
            redrawLinks(app);
        end
        function redrawNodes(app)
            N=length(app.lattice.nodes);
            if N>0
                %set(app.UIAxes, 'Visible','off')
                hold(app.UIAxes,'on')
                if ~isempty(app.unitcell)
                    unc=[app.unitcell.ID];
                else
                    unc=-1;
                end
                for i=1:N
                    snode=app.lattice.nodes(i);
                    pl=scatter3(app.UIAxes,round(snode.x),round(snode.y),1,app.nodeRadius,'filled','r');
                    pl.ButtonDownFcn=createCallbackFcn(app, @NodeCallback, true);
                    app.N_nodes=app.N_nodes+1;
                    pl.UserData.node=snode;
                    app.selectedNodeScatter=pl;
                    %highlight unit cell
                    if sum(unc==snode.ID)
                        pl.MarkerFaceColor='green';
                    end
                    %higlight unit cell
                end
                %set(app.UIAxes, 'Visible','on')
                app.selectedNode=app.lattice.nodes(end);
                highlightSelectedNode(app,pl);
            end
        end
        function  UpdatePrimitiveVectors(app)
            v1=app.UITableVectors.Data(1,:);
            v2=app.UITableVectors.Data(2,:);
            if isempty(app.primitiveVectors)
                hold(app.UIAxes,'on');
            else
                delete(app.primitiveVectors(:));
            end
            app.primitiveVectors(1)=quiver(1,1,v1(1),v1(2) ...
                ,'LineWidth',2,'MaxHeadSize',0.5,'color',[1 0 0.5],'Parent',app.UIAxes,'AutoScale','off');
            app.primitiveVectors(2)=quiver(1,1,v2(1),v2(2) ...
                ,'LineWidth',2,'MaxHeadSize',0.5,'color',[1 0 0.5],'Parent',app.UIAxes,'AutoScale','off');
            app.lattice.primitiveVectors=app.UITableVectors.Data;
        end
        
        %this function assumes all the link arrows are already deleted
        %and draws them
        function redrawLinks(app)
            %now we go through all the nodes and draw their links, if they are not conjugate
            for i=1:length(app.lattice.nodes)
                snode=app.lattice.nodes(i);
                nlinks=length(snode.linklist);
                for j=1:nlinks
                    slink=snode.linklist(j);
                    connectedNode=slink.node;
                    if ~slink.isConjugate
                        arrow=createLinkArrow(app,snode.x,snode.y,connectedNode.x,connectedNode.y);
                        arrow.UserData.link=slink;
                        arrow.UserData.nodes=[snode,connectedNode];
                    end
                end
            end
        end
        
        function nodes = getNodesAtPosition(app,x,y)
            nodes=Node.empty;
            for i=1:length(app.lattice.nodes)
                snode=app.lattice.nodes(i);
                if snode.x==x&&snode.y==y
                    nodes=[nodes snode];
                end
            end
        end
        
        function  updateParameterTable(app)
            names=fieldnames(app.lattice.par);
            values=struct2cell(app.lattice.par);
            app.UITablePar.Data=[names,cellfun(@num2str, values, 'un', 0)];
        end
        
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            include
            app.lattice=LatticeInteractive();
            app.selectedNode=Node.empty;
            app.unitcell=Node.empty();
            app.linkStrength=str2double(app.LinkstrengthEditField.Value);
            app.N_nodes=0;
            plotGrid(app);
            %initialize equations
            app.eqnlist=Misc.getEquations();
            app.EquationDropDown.Items=app.eqnlist;
            app.currentEqn=eval(app.EquationDropDown.Value);
            updateEqnTable(app);
            app.UITableVectors.Data=[1 0;0 1];
            UpdatePrimitiveVectors(app);
            %Visual.showNodes(lattice,app.UIAxes)
        end

        % Callback function
        function AddNodeButtonPushed(app, event)
            currentTool='addNode';
            app.Message.Value='Left click to add/select node, right click to link';
            
        end

        % Callback function
        function AddLinkButtonPushed(app, event)
            currentTool='addLink';
            app.Message.Value='Click first node to add link';
        end

        % Button pushed function: SaveLatticeButton
        function SaveLatticeButtonPushed(app, event)
            [file,path] = uiputfile('*.mat');
            f = fullfile(path,file);
            lattice=app.lattice;
            save(f,'lattice');
        end

        % Button pushed function: LoadLatticeButton
        function LoadLatticeButtonPushed(app, event)
            [file,path] = uigetfile('*.mat');
            f = fullfile(path,file);
            lat=load(f);
            undrawLattice(app);
            app.lattice=lat.lattice;
            redrawLattice(app);
            %update primitive vectors from the loaded data
            app.UITableVectors.Data=app.lattice.primitiveVectors;
            UpdatePrimitiveVectors(app);
            updateParameterTable(app);
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            %Update current Equation object
            data=app.UITable.Data;
            for i=1:length(data)
                currentField=data(i,1);
                currentValue=data(i,2);
                disp(currentValue{1});
                if ~isempty(str2double(currentValue{1}))&&~isnan(str2double(currentValue{1}))
                    app.currentEqn.par.(currentField{1})=str2double(currentValue{1});
                else
                    app.Message.Value='non-numeric';
                    app.currentEqn.par.(currentField{1})=currentValue{1};
                end
            end
            %update current node's equation with the new one
            if ~isempty(app.selectedNode)
                app.selectedNode.eqn=app.currentEqn;
            end
        end

        % Value changed function: EquationDropDown
        function EquationDropDownValueChanged(app, event)
            value = app.EquationDropDown.Value;
            app.currentEqn=eval(app.EquationDropDown.Value);
            updateEqnTable(app);
        end

        % Button pushed function: ButtonAddLatPar
        function ButtonAddLatParPushed(app, event)
            [x,~]=size(app.UITablePar.Data);
            %if isempty(data)
            app.UITablePar.Data=[app.UITablePar.Data; {strcat('par',num2str(x))},{0}];
            %end
            %update lattice object with parameters
            app.lattice.par=cell2struct(app.UITablePar.Data(:,2),app.UITablePar.Data(:,1),1);
        end

        % Cell edit callback: UITablePar
        function UITableParCellEdit(app, event)
            
            %update lattice object with parameters
            app.lattice.par=cell2struct(app.UITablePar.Data(:,2),app.UITablePar.Data(:,1),1);
        end

        % Button pushed function: ClearLatticeButton
        function ClearLatticeButtonPushed(app, event)
            answer = questdlg('Clear the lattice?',' ', 'Yes','No','No');
            switch answer
                case 'Yes'
                    undrawLattice(app);
                    app.lattice=LatticeInteractive();
                case 'No'
            end
        end

        % Value changed function: LinkstrengthEditField
        function LinkstrengthEditFieldValueChanged(app, event)
            value = app.LinkstrengthEditField.Value;
            if ~isempty(str2double(value))&&~isnan(str2double(value))
                app.linkStrength=str2double(value);
            elseif ~isempty(value)
                app.linkStrength=value;
            else %if empty
                app.LinkstrengthEditField.Value=num2str(app.linkStrength);
            end
            if app.selectedNodeScatter~=0&&~isempty(app.selectedNodeScatter)
                nodes=app.selectedLinkArrow.UserData.nodes;
                %re-attach nodes with new strength
                Node.detach(nodes(1),nodes(2));
                [link1,~]=Node.attach(nodes(1),nodes(2),app.linkStrength);
                app.selectedLinkArrow.UserData.link=link1;
            end
        end

        % Callback function
        function PrimitiveVectorsButtonPushed(app, event)
            
        end

        % Callback function
        function PeriodicCheckBoxValueChanged(app, event)
            
        end

        % Cell edit callback: UITableVectors
        function UITableVectorsCellEdit(app, event)
            UpdatePrimitiveVectors(app);
        end

        % Button pushed function: DeleteNodeButton
        function DeleteNodeButtonPushed(app, event)
            %delete node
            if ~isempty(app.selectedNode)
                Node.deleteNodes(app.selectedNode);
                app.lattice.nodes=Node.cleanup(app.lattice.nodes);
                app.selectedNode=Node.empty;
                delete(app.selectedNodeScatter);
                app.selectedNodeScatter=[];
                undrawLattice(app);
                redrawLattice(app);
            else
                app.Message.Value='No node selected';
            end
        end

        % Button pushed function: DeleteLinkButton
        function DeleteLinkButtonPushed(app, event)
            if ~isempty(app.selectedLinkArrow)&& app.selectedLinkArrow~=0
                node1=app.selectedLinkArrow.UserData.nodes(1);
                node2=app.selectedLinkArrow.UserData.nodes(2);
                Node.detach(node1,node2);
                delete(app.selectedLinkArrow);
                app.selectedLinkArrow=0;
            end
        end

        % Menu selected function: MovelatticeMenu
        function MovelatticeMenuSelected(app, event)
            prompt = {'X offset:','Y offset:'};
            dlgtitle = 'Move Lattice';
            dims = [1 35];
            definput = {'0','0'};
            answer = inputdlg(prompt,dlgtitle,dims,definput);
            if ~isempty(answer)&&length(answer)==2
                x=str2double(answer{1});
                y=str2double(answer{2});
                Node.move(app.lattice,floor(x),floor(y));
                undrawLattice(app);
                redrawLattice(app);
            end
        end

        % Menu selected function: MarkunitcellMenu
        function MarkunitcellMenuSelected(app, event)
            app.unitcell=app.lattice.getUnitCell();
            undrawLattice(app);
            redrawLattice(app);
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure;
            app.UIFigure.Name = 'UI Figure';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.EditMenu = uimenu(app.UIFigure);
            app.EditMenu.Text = 'Edit';
            app.MovelatticeMenu = uimenu(app.EditMenu);
            app.MovelatticeMenu.MenuSelectedFcn = createCallbackFcn(app, @MovelatticeMenuSelected, true);
            app.MovelatticeMenu.Accelerator = 'M';
            app.MovelatticeMenu.Text = 'Move lattice';
            app.MarkunitcellMenu = uimenu(app.EditMenu);
            app.MarkunitcellMenu.MenuSelectedFcn = createCallbackFcn(app, @MarkunitcellMenuSelected, true);
            app.MarkunitcellMenu.Text = 'Mark unit cell';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.EditorTab = uitab(app.TabGroup);
            app.EditorTab.Title = 'Editor                                   ';
            app.EquationDropDownLabel = uilabel(app.EditorTab);
            app.EquationDropDownLabel.Text = 'Equation';
            app.EquationDropDown = uidropdown(app.EditorTab);
            app.EquationDropDown.ValueChangedFcn = createCallbackFcn(app, @EquationDropDownValueChanged, true);
            app.EquationParametersLabel = uilabel(app.EditorTab);
            app.EquationParametersLabel.Text = 'Equation Parameters';
            app.UITable = uitable(app.EditorTab);
            app.UITable.ColumnName = {'Parameter'; 'Value'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = [false true];
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.LinkstrengthEditFieldLabel = uilabel(app.EditorTab);
            app.LinkstrengthEditFieldLabel.Text = 'Link strength';
            app.LinkstrengthEditField = uieditfield(app.EditorTab, 'text');
            app.LinkstrengthEditField.ValueChangedFcn = createCallbackFcn(app, @LinkstrengthEditFieldValueChanged, true);
            app.LinkstrengthEditField.Value = '1';
            app.LinktypeButtonGroup = uibuttongroup(app.EditorTab);
            app.LinktypeButtonGroup.Title = 'Link type';
            app.HermitianButton = uiradiobutton(app.LinktypeButtonGroup);
            app.HermitianButton.Text = 'Hermitian';
            app.HermitianButton.Value = true;
            app.nonHermitianButton = uiradiobutton(app.LinktypeButtonGroup);
            app.nonHermitianButton.Enable = 'off';
            app.nonHermitianButton.Text = 'non-Hermitian';
            app.SaveLatticeButton = uibutton(app.EditorTab, 'push');
            app.SaveLatticeButton.ButtonPushedFcn = createCallbackFcn(app, @SaveLatticeButtonPushed, true);
            app.SaveLatticeButton.Text = 'Save Lattice';
            app.LoadLatticeButton = uibutton(app.EditorTab, 'push');
            app.LoadLatticeButton.ButtonPushedFcn = createCallbackFcn(app, @LoadLatticeButtonPushed, true);
            app.LoadLatticeButton.Text = 'Load Lattice';
            app.UITablePar = uitable(app.EditorTab);
            app.UITablePar.ColumnName = {'Parameter'; 'Value'};
            app.UITablePar.RowName = {};
            app.UITablePar.ColumnEditable = true;
            app.UITablePar.CellEditCallback = createCallbackFcn(app, @UITableParCellEdit, true);
            app.LatticeParametersLabel = uilabel(app.EditorTab);
            app.LatticeParametersLabel.Text = 'Lattice Parameters';
            app.ButtonAddLatPar = uibutton(app.EditorTab, 'push');
            app.ButtonAddLatPar.ButtonPushedFcn = createCallbackFcn(app, @ButtonAddLatParPushed, true);
            app.ButtonAddLatPar.Text = '➕';
            app.ButtonRemLatPar = uibutton(app.EditorTab, 'push');
            app.ButtonRemLatPar.Text = '➖';
            app.ClearLatticeButton = uibutton(app.EditorTab, 'push');
            app.ClearLatticeButton.ButtonPushedFcn = createCallbackFcn(app, @ClearLatticeButtonPushed, true);
            app.ClearLatticeButton.Text = 'Clear Lattice';
            app.MakeFiniteLatticeButton = uibutton(app.EditorTab, 'push');
            app.MakeFiniteLatticeButton.Text = 'Make Finite Lattice';
            app.PrimitiveVectorsLabel = uilabel(app.EditorTab);
            app.PrimitiveVectorsLabel.Text = 'Primitive Vectors';
            app.UITableVectors = uitable(app.EditorTab);
            app.UITableVectors.ColumnName = {'X'; 'Y'};
            app.UITableVectors.RowName = {};
            app.UITableVectors.ColumnEditable = true;
            app.UITableVectors.CellEditCallback = createCallbackFcn(app, @UITableVectorsCellEdit, true);
            app.DeleteNodeButton = uibutton(app.EditorTab, 'push');
            app.DeleteNodeButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteNodeButtonPushed, true);
            app.DeleteNodeButton.Text = 'Delete Node';
            app.DeleteLinkButton = uibutton(app.EditorTab, 'push');
            app.DeleteLinkButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteLinkButtonPushed, true);
            app.DeleteLinkButton.Text = 'Delete Link';
            app.Label = uilabel(app.EditorTab);
            app.Label.Text = '';
            app.Message = uieditfield(app.EditorTab, 'text');
            app.Message.Editable = 'off';
            app.UIAxes = uiaxes(app.EditorTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            app.EigenvaluesolverTab = uitab(app.TabGroup);
            app.EigenvaluesolverTab.Title = 'Eigenvalue solver                 ';
            app.UIAxes_eig = uiaxes(app.EigenvaluesolverTab);
            title(app.UIAxes_eig, 'Title')
            xlabel(app.UIAxes_eig, 'X')
            ylabel(app.UIAxes_eig, 'Y')
            app.SliderLabel = uilabel(app.EigenvaluesolverTab);
            app.SliderLabel.Text = 'Slider';
            app.Slider = uislider(app.EigenvaluesolverTab);
        end
    end

    methods (Access = public)

        % Construct app
        function app = LatticeEditor

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end